classdef DrawFigure < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DrawFigureUIFigure          matlab.ui.Figure
        RightPanel                  matlab.ui.container.Panel
        ResolutionEditField         matlab.ui.control.NumericEditField
        ResolutionEditFieldLabel    matlab.ui.control.Label
        LoadParametersButton        matlab.ui.control.Button
        SaveParametersButton        matlab.ui.control.Button
        LimitedDataEditField        matlab.ui.control.NumericEditField
        LimitedDataSwitch           matlab.ui.control.Switch
        LegendLabelsEditField       matlab.ui.control.EditField
        ErrorSwitch                 matlab.ui.control.Switch
        LegendSwitch                matlab.ui.control.Switch
        SiginificanceSwitch         matlab.ui.control.Switch
        Tree                        matlab.ui.container.CheckBoxTree
        SaveFormatNode              matlab.ui.container.TreeNode
        tifNode                     matlab.ui.container.TreeNode
        epfNode                     matlab.ui.container.TreeNode
        pdfNode                     matlab.ui.container.TreeNode
        DrawButton                  matlab.ui.control.Button
        LoadDataButton              matlab.ui.control.Button
        SaveSwitch                  matlab.ui.control.Switch
        LeftPanel                   matlab.ui.container.Panel
        FontSizeEditField           matlab.ui.control.NumericEditField
        FontSizeEditFieldLabel      matlab.ui.control.Label
        TitleTextArea               matlab.ui.control.TextArea
        TitleTextAreaLabel          matlab.ui.control.Label
        OutlierRemoveDropDown       matlab.ui.control.DropDown
        OutlierRemoveDropDownLabel  matlab.ui.control.Label
        FontNameEditField           matlab.ui.control.EditField
        FontNameEditFieldLabel      matlab.ui.control.Label
        XTickLableTextArea          matlab.ui.control.TextArea
        XTickLableTextAreaLabel     matlab.ui.control.Label
        XLabelTextArea              matlab.ui.control.TextArea
        xLabelTextAreaLabel         matlab.ui.control.Label
        FigureTypeDropDown          matlab.ui.control.DropDown
        FigureTypeDropDownLabel     matlab.ui.control.Label
        SettingsLabel               matlab.ui.control.Label
    end

    
    properties (Access = public)
        %Use MVC pattern to draw the figures
        ModelObj; %Model object to hold the dataset and parameters
        ControllerObj; %Controller object to control all the callback
    end
    
    methods (Access = private)
        
        function DataChangedFcn(app)
            app.ControllerObj.DataChangedCallback();

        end
        function ParametersChangedFcn(app)
            app.ControllerObj.ParametersChangedCallback();
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        %Add all the subfolders to path
            pathToMLAPP = fileparts(mfilename('fullpath'));
            addpath(genpath(pathToMLAPP));
        
        %Create a default Model object
            app.ModelObj = Model(); 
            app.ControllerObj = Controller(app);

        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonCallbackFun(app, event)
            app.Dataset = uigetfile('*.mat', ...
                'Select an .mat data', '.\Examples\Dataset.mat');

            %Message when loading is cancelled
            if isequal(app.Dataset,0)                
                disp('User selected Cancel') 
            end
        end

        % Button pushed function: DrawButton
        function DrawButtonCallbackFun(app, event)
            View.GetView(app);
        end

        % Value changed function: FigureTypeDropDown
        function FigureTypeDropDownValueChanged(app, event)
            value = app.FigureTypeDropDown.Value;
            app.ControllerObj.DataChangedCallback('FigureType', value);
        end

        % Value changed function: XLabelTextArea
        function XLabelTextAreaValueChanged(app, event)
            value = app.XLabelTextArea.Value;
            app.ControllerObj.ParametersChangedCallback('XLabel', value);
        end

        % Value changed function: XTickLableTextArea
        function XTickLableTextAreaValueChanged(app, event)
            value = app.XTickLableTextArea.Value;
            app.ControllerObj.ParametersChangedCallback('XTickLabel', value);
        end

        % Value changed function: FontNameEditField
        function FontNameEditFieldValueChanged(app, event)
            value = app.FontNameEditField.Value;
            app.ControllerObj.ParametersChangedCallback('FontName', value);
        end

        % Callback function: not associated with a component
        function FontSizeEditFieldValueChanged(app, event)
            value = app.FontSizeEditField.Value;
            app.ControllerObj.ParametersChangedCallback('FontSize', value);
        end

        % Value changed function: TitleTextArea
        function TitleTextAreaValueChanged(app, event)
            value = app.TitleTextArea.Value;
            pp.ControllerObj.ParametersChangedCallback('Title', value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.DrawFigureUIFigure = uifigure('Visible', 'off');
            app.DrawFigureUIFigure.Name = 'DrawFigure';
            app.DrawFigureUIFigure.Icon = fullfile(pathToMLAPP, 'Icons', 'Settings.jpg');
            app.LeftPanel = uipanel(app.DrawFigureUIFigure);
            app.SettingsLabel = uilabel(app.LeftPanel);
            app.SettingsLabel.Text = 'Settings';
            app.FigureTypeDropDownLabel = uilabel(app.LeftPanel);
            app.FigureTypeDropDownLabel.Text = 'Figure Type';
            app.FigureTypeDropDown = uidropdown(app.LeftPanel);
            app.FigureTypeDropDown.Items = {'Bar', 'Line', 'Violin', 'Box'};
            app.FigureTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @FigureTypeDropDownValueChanged, true);
            app.FigureTypeDropDown.Value = 'Bar';
            app.xLabelTextAreaLabel = uilabel(app.LeftPanel);
            app.xLabelTextAreaLabel.Text = 'XLabel';
            app.XLabelTextArea = uitextarea(app.LeftPanel);
            app.XLabelTextArea.ValueChangedFcn = createCallbackFcn(app, @XLabelTextAreaValueChanged, true);
            app.XLabelTextArea.Value = {'DIV'};
            app.XTickLableTextAreaLabel = uilabel(app.LeftPanel);
            app.XTickLableTextAreaLabel.Text = 'XTickLable';
            app.XTickLableTextArea = uitextarea(app.LeftPanel);
            app.XTickLableTextArea.ValueChangedFcn = createCallbackFcn(app, @XTickLableTextAreaValueChanged, true);
            app.XTickLableTextArea.Value = {'{3,4,5,6,7,9,11}'};
            app.FontNameEditFieldLabel = uilabel(app.LeftPanel);
            app.FontNameEditFieldLabel.Text = 'FontName';
            app.FontNameEditField = uieditfield(app.LeftPanel, 'text');
            app.FontNameEditField.ValueChangedFcn = createCallbackFcn(app, @FontNameEditFieldValueChanged, true);
            app.FontNameEditField.Value = 'Arial';
            app.OutlierRemoveDropDownLabel = uilabel(app.LeftPanel);
            app.OutlierRemoveDropDownLabel.Text = 'OutlierRemove';
            app.OutlierRemoveDropDown = uidropdown(app.LeftPanel);
            app.OutlierRemoveDropDown.Items = {'none', 'quartiles', 'median'};
            app.OutlierRemoveDropDown.Value = 'none';
            app.TitleTextAreaLabel = uilabel(app.LeftPanel);
            app.TitleTextAreaLabel.Text = 'Title';
            app.TitleTextArea = uitextarea(app.LeftPanel);
            app.TitleTextArea.ValueChangedFcn = createCallbackFcn(app, @TitleTextAreaValueChanged, true);
            app.TitleTextArea.Placeholder = 'No. of 1st dendrite';
            app.FontSizeEditFieldLabel = uilabel(app.LeftPanel);
            app.FontSizeEditFieldLabel.Text = 'FontSize';
            app.FontSizeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FontSizeEditField.Value = 10;
            app.RightPanel = uipanel(app.DrawFigureUIFigure);
            app.SaveSwitch = uiswitch(app.RightPanel, 'slider');
            app.SaveSwitch.Items = {'AutoSave', ''};
            app.SaveSwitch.Value = 'AutoSave';
            app.LoadDataButton = uibutton(app.RightPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonCallbackFun, true);
            app.LoadDataButton.Text = 'Load Data';
            app.DrawButton = uibutton(app.RightPanel, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @DrawButtonCallbackFun, true);
            app.DrawButton.Text = 'Draw';
            app.Tree = uitree(app.RightPanel, 'checkbox');
            app.SaveFormatNode = uitreenode(app.Tree);
            app.SaveFormatNode.Text = 'SaveFormat';
            app.tifNode = uitreenode(app.SaveFormatNode);
            app.tifNode.Text = 'tif';
            app.epfNode = uitreenode(app.SaveFormatNode);
            app.epfNode.Text = 'epf';
            app.pdfNode = uitreenode(app.SaveFormatNode);
            app.pdfNode.Text = 'pdf';
            app.SiginificanceSwitch = uiswitch(app.RightPanel, 'slider');
            app.SiginificanceSwitch.Items = {'Significance', ''};
            app.SiginificanceSwitch.Value = 'Significance';
            app.LegendSwitch = uiswitch(app.RightPanel, 'slider');
            app.LegendSwitch.Items = {'Legend', ''};
            app.LegendSwitch.Value = 'Legend';
            app.ErrorSwitch = uiswitch(app.RightPanel, 'slider');
            app.ErrorSwitch.Items = {'Error', ''};
            app.ErrorSwitch.Value = 'Error';
            app.LegendLabelsEditField = uieditfield(app.RightPanel, 'text');
            app.LimitedDataSwitch = uiswitch(app.RightPanel, 'slider');
            app.LimitedDataSwitch.Items = {'LimitedData', ''};
            app.LimitedDataSwitch.Value = 'LimitedData';
            app.LimitedDataEditField = uieditfield(app.RightPanel, 'numeric');
            app.LimitedDataEditField.Value = 1;
            app.SaveParametersButton = uibutton(app.RightPanel, 'push');
            app.SaveParametersButton.Text = 'Save Parameters';
            app.LoadParametersButton = uibutton(app.RightPanel, 'push');
            app.LoadParametersButton.Text = 'Load Parameters';
            app.ResolutionEditFieldLabel = uilabel(app.RightPanel);
            app.ResolutionEditFieldLabel.Text = 'Resolution';
            app.ResolutionEditField = uieditfield(app.RightPanel, 'numeric');
            app.ResolutionEditField.Value = 1200;
            % Show the figure after all components are created
            app.DrawFigureUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DrawFigure

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.DrawFigureUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.DrawFigureUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DrawFigureUIFigure)
        end
    end
end